classdef LFsim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Menu_file                matlab.ui.container.Menu
        Menu_file_laser          matlab.ui.container.Menu
        Menu_file_detector       matlab.ui.container.Menu
        Menu_help                matlab.ui.container.Menu
        Menu_file_mandroid       matlab.ui.container.Menu
        Menu_about_us            matlab.ui.container.Menu
        Menu_code_address        matlab.ui.container.Menu
        GridLayout               matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        Tab_lasersim             matlab.ui.container.Tab
        Tab_footprintsim         matlab.ui.container.Tab
        GridLayout2              matlab.ui.container.GridLayout
        Panel_ParaIn             matlab.ui.container.Panel
        mLabel                   matlab.ui.control.Label
        EditField_LaserDiameter  matlab.ui.control.NumericEditField
        mLabel_2                 matlab.ui.control.Label
        EditField_DetectorMin    matlab.ui.control.NumericEditField
        mLabel_3                 matlab.ui.control.Label
        EditField_DetectorMax    matlab.ui.control.NumericEditField
        mLabel_4                 matlab.ui.control.Label
        EditField_DetectorStep   matlab.ui.control.NumericEditField
        mLabel_5                 matlab.ui.control.Label
        EditField_LaserDis       matlab.ui.control.NumericEditField
        Label_4                  matlab.ui.control.Label
        EditField_LaserNum       matlab.ui.control.NumericEditField
        Label_5                  matlab.ui.control.Label
        EditField_SlopeMax       matlab.ui.control.NumericEditField
        Label_3                  matlab.ui.control.Label
        EditField_SlopeStep      matlab.ui.control.NumericEditField
        Button_StartCal          matlab.ui.control.Button
        Button_StartSim          matlab.ui.control.Button
        Button_Stop              matlab.ui.control.Button
        UITable_Error            matlab.ui.control.Table
        UIAxes_Porcess           matlab.ui.control.UIAxes
        UIAxes_Error             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        TMO % 总的均方差
        TMSD % 均方差误差   
        delta   % 探测器间隔
        isstopsim % 是否停止仿真
    end
    
    methods (Access = private)
        
        function errorplot(app,delta,TMO,TMSD)
            errorbar(app.UIAxes_Error,delta,TMO,TMSD);
        end
        
        function plotPoint(app,pointx_min,pointx_max,pointy_min,pointy_max,step)
            pointx = pointx_min:step:pointx_max;
            pointy = pointy_min:step:pointy_max;
            [X,Y] = meshgrid(pointx,pointy);
            plot(app.UIAxes_Porcess,X,Y,'k.');
        end
        
        function plotProcessCircle(app,centrex,centrey,diameter)   % 在Process里面画圆
            pos = [centrex-diameter/2,centrey-diameter/2,diameter,diameter];
            rectangle(app.UIAxes_Porcess,'Position',pos,'Curvature',[1,1]);
            axis(app.UIAxes_Porcess,'equal');
            axis(app.UIAxes_Porcess,'auto x');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 窗口大小设置
            sc_size = get(0,'ScreenSize');
            width = sc_size(3);height = sc_size(4);
            app.UIFigure.Position = [width/8,height/8,width/1.3,height/1.3];
        end

        % Button pushed function: Button_StartSim
        function Button_StartSimPushed(app, event)
            diameter = app.EditField_LaserDiameter.Value;                   % 激光光斑直径
            radium = diameter/2;
            delta_min= app.EditField_DetectorMin.Value;                     % 探测点之间距离的最小值
            delta_max = app.EditField_DetectorMax.Value;                    % 探测点之间距离的最大值
            step_delta = app.EditField_DetectorStep.Value;                  % 探测点之间距离的步长
            app.delta = delta_min:step_delta:delta_max;
            slope_step = app.EditField_SlopeStep.Value;
            slope_max = app.EditField_SlopeMax.Value;
            slope_angle = 0:slope_step:slope_max;
            circle_distance = app.EditField_LaserDis.Value;                 % 光斑与光斑之间的距离
            dector_x_min = 0;dector_x_max = 500+diameter;
            dector_y_min = -35;dector_y_max = 500+diameter;
            app.isstopsim = false;

            for delta_i = app.delta 
                % 每一个距离计算一次TMO和TMSD,delta_i代表当前的Δ
                % 计算得到三个圆的圆心坐标值
                centroidxy1 = calCentroid(delta_i,radium,slope_angle,circle_distance,1);
                centroidxy2 = calCentroid(delta_i,radium,slope_angle,circle_distance,2);
                centroidxy3 = calCentroid(delta_i,radium,slope_angle,circle_distance,3);
            end
                plotPoint(app,dector_x_min,dector_x_max,dector_y_min,dector_y_max,delta_min);
                hold(app.UIAxes_Porcess,'on');
            for i=1:1:length(centroidxy1)
                plotProcessCircle(app,centroidxy1(1,i),centroidxy1(2,i),diameter);
                plotProcessCircle(app,centroidxy2(1,i),centroidxy2(2,i),diameter);
                plotProcessCircle(app,centroidxy3(1,i),centroidxy3(2,i),diameter);
                plot(app.UIAxes_Porcess,[centroidxy1(1,i);centroidxy3(1,i)],[centroidxy1(2,i),centroidxy3(2,i)]);
                pause(0.5);               
                if app.isstopsim == true                     
                    return;
                end                
            end
            
        end

        % Button pushed function: Button_Stop
        function Button_StopPushed(app, event)
            app.isstopsim=true;
            cla(app.UIAxes_Porcess);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.isstopsim = true;
            delete(app);
            clear;          
        end

        % Button pushed function: Button_StartCal
        function Button_StartCalPushed(app, event)
            diameter = app.EditField_LaserDiameter.Value;                   % 激光光斑直径                                    
            delta_min= app.EditField_DetectorMin.Value;
            delta_max = app.EditField_DetectorMax.Value;                    % 探测点之间距离的最值
            step_delta = app.EditField_DetectorStep.Value;
            circle_distance = app.EditField_LaserDis.Value;                 % 光斑与光斑之间的距离
            circle_number = app.EditField_LaserNum.Value;                   % 光斑的个数 
            slope_step = app.EditField_SlopeStep.Value;
            slope_max = app.EditField_SlopeMax.Value;
            app.delta = delta_min:step_delta:delta_max;
            start_msg = msgbox('正在计算,请勿关闭...');
            tic;
            [app.TMO,app.TMSD] = simDetector(diameter,delta_min,delta_max,step_delta,circle_distance,circle_number,slope_step,slope_max);
            elapsedtime = toc;
            close(start_msg);
            msgbox({['完成计算,共耗时',num2str(elapsedtime),'秒'];'请关闭对话框'});
            % 输出误差值
            d = table(app.delta',app.TMO',app.TMSD');
            app.UITable_Error.Data = d;
            % 绘制误差曲线
            errorplot(app,app.delta,app.TMO,app.TMSD);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '激光足印仿真';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu_file = uimenu(app.UIFigure);
            app.Menu_file.Text = '文件';
            app.Menu_file_laser = uimenu(app.Menu_file);
            app.Menu_file_laser.Text = '激光参数导入';
            app.Menu_file_detector = uimenu(app.Menu_file);
            app.Menu_file_detector.Text = '探测器参数导入';
            app.Menu_help = uimenu(app.UIFigure);
            app.Menu_help.Text = '帮助';
            app.Menu_file_mandroid = uimenu(app.Menu_help);
            app.Menu_file_mandroid.Text = '文件格式说明';
            app.Menu_about_us = uimenu(app.Menu_help);
            app.Menu_about_us.Text = '关于我们';
            app.Menu_code_address = uimenu(app.Menu_help);
            app.Menu_code_address.Text = '开源地址';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab_lasersim = uitab(app.TabGroup);
            app.Tab_lasersim.Title = '激光仿真';
            app.Tab_footprintsim = uitab(app.TabGroup);
            app.Tab_footprintsim.Title = '足印仿真';
            app.GridLayout2 = uigridlayout(app.Tab_footprintsim);
            app.Panel_ParaIn = uipanel(app.GridLayout2);
            app.Panel_ParaIn.Title = '参数输入';
            app.mLabel = uilabel(app.Panel_ParaIn);
            app.mLabel.Text = '激光光斑直径(m)';
            app.EditField_LaserDiameter = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_LaserDiameter.Limits = [10 100];
            app.EditField_LaserDiameter.Value = 70;
            app.mLabel_2 = uilabel(app.Panel_ParaIn);
            app.mLabel_2.Text = '探测器最小间距(m)';
            app.EditField_DetectorMin = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_DetectorMin.Limits = [10 20];
            app.EditField_DetectorMin.Value = 15;
            app.mLabel_3 = uilabel(app.Panel_ParaIn);
            app.mLabel_3.Text = '探测器最大间距(m)';
            app.EditField_DetectorMax = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_DetectorMax.Limits = [30 40];
            app.EditField_DetectorMax.Value = 35;
            app.mLabel_4 = uilabel(app.Panel_ParaIn);
            app.mLabel_4.Text = '探测器间距步长(m)';
            app.EditField_DetectorStep = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_DetectorStep.Limits = [1 2];
            app.EditField_DetectorStep.Value = 1;
            app.mLabel_5 = uilabel(app.Panel_ParaIn);
            app.mLabel_5.Text = '光斑与光斑距离(m)';
            app.EditField_LaserDis = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_LaserDis.Limits = [100 200];
            app.EditField_LaserDis.Value = 170;
            app.Label_4 = uilabel(app.Panel_ParaIn);
            app.Label_4.Enable = 'off';
            app.Label_4.Text = '光斑个数(个)';
            app.EditField_LaserNum = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_LaserNum.Limits = [3 3.1];
            app.EditField_LaserNum.RoundFractionalValues = 'on';
            app.EditField_LaserNum.Enable = 'off';
            app.EditField_LaserNum.Value = 3;
            app.Label_5 = uilabel(app.Panel_ParaIn);
            app.Label_5.Text = '倾斜角最大值(°)';
            app.EditField_SlopeMax = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_SlopeMax.Limits = [15 45];
            app.EditField_SlopeMax.Value = 15;
            app.Label_3 = uilabel(app.Panel_ParaIn);
            app.Label_3.Text = '倾斜角刻度(°)';
            app.EditField_SlopeStep = uieditfield(app.Panel_ParaIn, 'numeric');
            app.EditField_SlopeStep.Limits = [0.5 2];
            app.EditField_SlopeStep.Value = 1;
            app.Button_StartCal = uibutton(app.Panel_ParaIn, 'push');
            app.Button_StartCal.ButtonPushedFcn = createCallbackFcn(app, @Button_StartCalPushed, true);
            app.Button_StartCal.Text = '开始计算';
            app.Button_StartSim = uibutton(app.Panel_ParaIn, 'push');
            app.Button_StartSim.ButtonPushedFcn = createCallbackFcn(app, @Button_StartSimPushed, true);
            app.Button_StartSim.Text = '开始仿真';
            app.Button_Stop = uibutton(app.Panel_ParaIn, 'push');
            app.Button_Stop.ButtonPushedFcn = createCallbackFcn(app, @Button_StopPushed, true);
            app.Button_Stop.Text = '停止仿真';
            app.UITable_Error = uitable(app.GridLayout2);
            app.UITable_Error.ColumnName = {'Array Detector Spacing(m)'; 'Total Mean Offset(m)'; 'Total Mean Standard Deviation(m)'};
            app.UITable_Error.RowName = {};
            app.UITable_Error.ColumnSortable = true;
            app.UITable_Error.ColumnEditable = true;
            app.UIAxes_Porcess = uiaxes(app.GridLayout2);
            title(app.UIAxes_Porcess, '仿真过程')
            xlabel(app.UIAxes_Porcess, 'X Direction')
            ylabel(app.UIAxes_Porcess, 'Y Direction')
            app.UIAxes_Error = uiaxes(app.GridLayout2);
            title(app.UIAxes_Error, '误差曲线')
            xlabel(app.UIAxes_Error, 'Array Detector Spacing(m)')
            ylabel(app.UIAxes_Error, 'Total Mean Offset(m)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LFsim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end