classdef ProFP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        Panel_3          matlab.ui.container.Panel
        GridLayout2      matlab.ui.container.GridLayout
        Image            matlab.ui.control.Image
        Panel_2          matlab.ui.container.Panel
        Panel_4          matlab.ui.container.Panel
        GridLayout3      matlab.ui.container.GridLayout
        Panel_5          matlab.ui.container.Panel
        Lamp             matlab.ui.control.Lamp
        Label            matlab.ui.control.Label
        Label_2          matlab.ui.control.Label
        Button           matlab.ui.control.Button
        DropDownLabel    matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        Switch           matlab.ui.control.Switch
        Button_2         matlab.ui.control.Button
        XEditFieldLabel  matlab.ui.control.Label
        XEditField       matlab.ui.control.NumericEditField
        YEditFieldLabel  matlab.ui.control.Label
        YEditField       matlab.ui.control.NumericEditField
        EditFieldLabel   matlab.ui.control.Label
        EditField        matlab.ui.control.NumericEditField
        UITable          matlab.ui.control.Table
    end

    
    properties (Access = private)
        CallingApp % Description
        SerialPort % Description
        Count % Description
        matrix % Description
    end
    
    methods (Access = private)
        
        function [] = getSerialData(app,obj,~)
            str = fgetl(obj);
            oldData = get(app.UITable,'Data');
            % 正则表达式分割数组
            strCell = regexp(str,',','split');
            Address = getAddressCode(strCell{2});
            [PositionLatStr,~] = getLatitude(strCell{3});
            [PositionLonStr,~] = getLongitude(strCell{4});
            myTime = datestr(getTime(strCell{5}));
            TriggerTime = getTriggerTime(strCell{6});
            Power = getPower(strCell(7));
            % 添加数组数据进表格
            newRow = {app.Count,Address,PositionLatStr,PositionLonStr,myTime,TriggerTime,Power};
            newData = [oldData; newRow];
            % 输入数据进行排序
            newData = sortrows(newData,2,'descend');
            set(app.UITable,'Data',newData);
            [row,col] = app.getPos(6);
            app.matrix(row,col) = Power;
            
            heatmap(app.Panel_2,app.matrix);
            
            app.Count=app.Count+1;
        end
        
        function [] = closeSerial(app)
            try
                scom = instrfind;
                fclose(scom);
                delete(scom); 
            catch
                uiconfirm(app.UIFigure,'串口关闭失败',"错误",'Icon','error');
            end
        end
        
        function [] = openSerial(app,s)
            try                                             % 异常捕捉
                fopen(s);
            catch
                uiconfirm(app.UIFigure,'串口创建失败',"错误",'Icon','error');
                app.Switch.Value = 'Off';
                app.Lamp.Color='r';
                app.Label_2.Text = '串口未打开';
            end
        end
        
        function [row,col] = getPos(app,line)
            num = app.Count;
            row = ceil(num/line);
            col = mod(num,line);
            if col==0
                col=6;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.matrix = zeros(6);
            app.Count = 1;
            app.Panel_2.AutoResizeChildren='off';
            app.Panel_3.AutoResizeChildren = 'off';
            app.Image.ImageSource = './LFSim_resources/webwxgetmsgimg.jpg';
            app.UITable.FontName = 'Microsoft YaHei';
            %% Test
            heatmap(app.Panel_2,app.matrix);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if strcmp(value,'Off')
                app.Lamp.Color='r';
                app.Label_2.Text = '串口未打开';
                app.closeSerial();
            else
                % 显示信息
                app.Lamp.Color = 'g';
                app.Label_2.Text = [app.DropDown.Value,'已打开'];
                % 串口接收设置
                scom = instrfind;
                if ~isempty(scom)
                    fclose(scom);
                    delete(scom); 
                end
                
                app.SerialPort = serial(app.DropDown.Value);
                set(app.SerialPort,'BaudRate',app.EditField.Value);
                set(app.SerialPort,'BytesAvailableFcnCount',69);
                set(app.SerialPort,'BytesAvailableFcnMode','byte');
                set(app.SerialPort,'BytesAvailableFcn',{@app.getSerialData});
                app.openSerial(app.SerialPort);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            stringArray = seriallist;
            if length(stringArray)==0
                stringArray = '无串口连接';
            end
            uiconfirm(app.UIFigure,stringArray,'硬件已连串口','Icon','info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel_3 = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.Image = uiimage(app.GridLayout2);
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Title = '接收细节';
            app.Panel_4 = uipanel(app.GridLayout);
            app.Panel_4.Title = '数据展示';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.Panel_5 = uipanel(app.GridLayout3);
            app.Panel_5.Title = '串口';
            app.Lamp = uilamp(app.Panel_5);
            app.Label = uilabel(app.Panel_5);
            app.Label.Text = '串口状态:';
            app.Label_2 = uilabel(app.Panel_5);
            app.Label_2.Text = '串口未打开';
            app.Button = uibutton(app.Panel_5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '查看硬件连接串口';
            app.DropDownLabel = uilabel(app.Panel_5);
            app.DropDownLabel.Text = '选择串口';
            app.DropDown = uidropdown(app.Panel_5);
            app.DropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4'};
            app.DropDown.Value = 'COM1';
            app.Switch = uiswitch(app.Panel_5, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Button_2 = uibutton(app.Panel_5, 'push');
            app.Button_2.Text = '计算质心';
            app.XEditFieldLabel = uilabel(app.Panel_5);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Panel_5, 'numeric');
            app.YEditFieldLabel = uilabel(app.Panel_5);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Panel_5, 'numeric');
            app.EditFieldLabel = uilabel(app.Panel_5);
            app.EditFieldLabel.Text = '波特率';
            app.EditField = uieditfield(app.Panel_5, 'numeric');
            app.EditField.Value = 9600;
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'No'; 'Address Number'; 'Latitude'; 'Longitude'; 'Time(UTC)'; 'Trigger Time'; 'Power(nJ/cm2)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false false false false true];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProFP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end